classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        AlignmentTab                    matlab.ui.container.Tab
        FilterSetupPanel                matlab.ui.container.Panel
        RespondEditField_5              matlab.ui.control.EditField
        RespondLabel_4                  matlab.ui.control.Label
        RespondEditField_4              matlab.ui.control.EditField
        RespondLabel_3                  matlab.ui.control.Label
        SaveCalibrationButton_2         matlab.ui.control.Button
        SaveFilterWaveEditField         matlab.ui.control.NumericEditField
        SaveFilterWaveEditFieldLabel    matlab.ui.control.Label
        SaveFilterStepEditField         matlab.ui.control.NumericEditField
        SaveFilterStepEditFieldLabel    matlab.ui.control.Label
        HomePositionButton_3            matlab.ui.control.Button
        RespondEditField_3              matlab.ui.control.EditField
        RespondEditField_3Label         matlab.ui.control.Label
        MoveButton_3                    matlab.ui.control.Button
        MoveMotortoRightNoofStepsEditField  matlab.ui.control.NumericEditField
        MoveMotortoRightNoofStepsEditFieldLabel  matlab.ui.control.Label
        RespondEditField_2              matlab.ui.control.EditField
        RespondLabel_2                  matlab.ui.control.Label
        MoveButton_2                    matlab.ui.control.Button
        MoveMotortoLeftNoofStepsEditField  matlab.ui.control.NumericEditField
        MoveMotortoLeftNoofStepsEditFieldLabel  matlab.ui.control.Label
        RespondEditField                matlab.ui.control.EditField
        RespondLabel                    matlab.ui.control.Label
        MoveButton                      matlab.ui.control.Button
        MoveMotortoFilterNoDropDown     matlab.ui.control.DropDown
        MoveMotortoFilterNoDropDownLabel  matlab.ui.control.Label
        HomePositionEditField_3         matlab.ui.control.NumericEditField
        HomePositionLabel               matlab.ui.control.Label
        SaveCalibrationButton           matlab.ui.control.Button
        CurrentWavelengthEditField_3    matlab.ui.control.NumericEditField
        CurrentWavelengthEditField_3Label  matlab.ui.control.Label
        CurrentPositionEditField_4      matlab.ui.control.NumericEditField
        CurrentPositionEditField_4Label  matlab.ui.control.Label
        UpdateCurrentWavelengthButton_3  matlab.ui.control.Button
        UpdateCurrentPositionButton_3   matlab.ui.control.Button
        LampChangeSetupPanel            matlab.ui.container.Panel
        CurrentPositionEditField_5      matlab.ui.control.EditField
        CurrentPositionEditField_5Label  matlab.ui.control.Label
        HomePositionEditField_2         matlab.ui.control.NumericEditField
        HomePositionEditField_2Label    matlab.ui.control.Label
        HomePositionButton_2            matlab.ui.control.Button
        CurrentWavelengthEditField_2    matlab.ui.control.NumericEditField
        CurrentWavelengthEditField_2Label  matlab.ui.control.Label
        UpdateCurrentWavelengthButton_2  matlab.ui.control.Button
        UpdateCurrentPositionButton_2   matlab.ui.control.Button
        LampCheckONOFFPanel             matlab.ui.container.Panel
        EditField_5                     matlab.ui.control.EditField
        TungstenButton_2                matlab.ui.control.Button
        D2Button_2                      matlab.ui.control.Button
        TungstenONOFFPanel              matlab.ui.container.Panel
        EditField_4                     matlab.ui.control.EditField
        Switch_2                        matlab.ui.control.Switch
        D2ONOFFPanel                    matlab.ui.container.Panel
        EditField_3                     matlab.ui.control.EditField
        Switch                          matlab.ui.control.Switch
        LampChangePanel                 matlab.ui.container.Panel
        EditField_2                     matlab.ui.control.EditField
        TungstenButton                  matlab.ui.control.Button
        D2Button                        matlab.ui.control.Button
        GratingTab                      matlab.ui.container.Tab
        Get_Motors_StepsPanel           matlab.ui.container.Panel
        Current_WavelengthEditField     matlab.ui.control.NumericEditField
        Current_WavelengthLabel         matlab.ui.control.Label
        Grating_Motor_StepsEditField    matlab.ui.control.NumericEditField
        Grating_Motor_StepsEditFieldLabel  matlab.ui.control.Label
        Filter_Motor_StepsEditField     matlab.ui.control.NumericEditField
        Filter_Motor_StepsEditFieldLabel  matlab.ui.control.Label
        Lamp_Motor_StepsEditField       matlab.ui.control.NumericEditField
        Lamp_Motor_StepsEditFieldLabel  matlab.ui.control.Label
        getmotorsstepsButton            matlab.ui.control.Button
        GratingSetupPanel               matlab.ui.container.Panel
        SampleNoEditField               matlab.ui.control.NumericEditField
        SampleNoEditFieldLabel          matlab.ui.control.Label
        ScanButton                      matlab.ui.control.Button
        StepWavelengthEditField         matlab.ui.control.NumericEditField
        StepWavelengthEditFieldLabel    matlab.ui.control.Label
        EditField_8                     matlab.ui.control.EditField
        EditField_7                     matlab.ui.control.EditField
        EditField3                      matlab.ui.control.EditField
        EditField2                      matlab.ui.control.EditField
        EditField_6                     matlab.ui.control.EditField
        SaveCalibrationButton_4         matlab.ui.control.Button
        SaveGratingWaveEditField        matlab.ui.control.NumericEditField
        SaveGratingWaveLabel            matlab.ui.control.Label
        SaveCalibrationButton_3         matlab.ui.control.Button
        SaveGratingStepEditField        matlab.ui.control.NumericEditField
        SaveGratingStepLabel            matlab.ui.control.Label
        MoveButton_6                    matlab.ui.control.Button
        MoveMotortoRightNoofStepsEditField_2  matlab.ui.control.NumericEditField
        MoveMotortoRightNoofStepsLabel  matlab.ui.control.Label
        MoveButton_5                    matlab.ui.control.Button
        MoveMotortoLeftNoofStepsEditField_2  matlab.ui.control.NumericEditField
        MoveMotortoLeftNoofStepsLabel   matlab.ui.control.Label
        MoveButton_4                    matlab.ui.control.Button
        MoveMotorToWavelengthNoEditField  matlab.ui.control.NumericEditField
        MoveMotorToWavelengthNoEditFieldLabel  matlab.ui.control.Label
        CurrentWavelengthEditField      matlab.ui.control.NumericEditField
        CurrentWavelengthEditFieldLabel  matlab.ui.control.Label
        UpdateCurrentWavelengthButton   matlab.ui.control.Button
        SaveButton_3                    matlab.ui.control.Button
        StartWavelengthEditField        matlab.ui.control.NumericEditField
        StartWavelengthEditFieldLabel   matlab.ui.control.Label
        StopWavelengthEditField         matlab.ui.control.NumericEditField
        StopWavelengthEditFieldLabel    matlab.ui.control.Label
        MeasureNoEditField              matlab.ui.control.NumericEditField
        MeasureNoLabel                  matlab.ui.control.Label
        CurrentPositionEditField        matlab.ui.control.NumericEditField
        CurrentPositionLabel            matlab.ui.control.Label
        UpdateCurrentPositionButton     matlab.ui.control.Button
        HomePositionButton              matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        HomePositionEditField           matlab.ui.control.NumericEditField
        HomePositionEditFieldLabel      matlab.ui.control.Label
        ReadingsTab                     matlab.ui.container.Tab
        PhotodetectorPanel              matlab.ui.container.Panel
        Slider                          matlab.ui.control.Slider
        Slider2                         matlab.ui.control.Slider
        EditField_10                    matlab.ui.control.EditField
        EditField_9                     matlab.ui.control.EditField
        SampleGainValueEditField        matlab.ui.control.NumericEditField
        SampleGainValueEditFieldLabel   matlab.ui.control.Label
        ChangeSampleGainValuetoButton   matlab.ui.control.Button
        ChangeReferenceGainValuetoButton  matlab.ui.control.Button
        Button2                         matlab.ui.control.Button
        ReferenceGainValueEditField     matlab.ui.control.NumericEditField
        ReferenceGainValueEditFieldLabel  matlab.ui.control.Label
        SampleAmplifiedEditField        matlab.ui.control.NumericEditField
        SampleAmplifiedEditFieldLabel   matlab.ui.control.Label
        ReferenceAmplifiedEditField     matlab.ui.control.NumericEditField
        ReferenceAmplifiedEditFieldLabel  matlab.ui.control.Label
        SampleEditField                 matlab.ui.control.NumericEditField
        SampleEditFieldLabel            matlab.ui.control.Label
        ReferanceEditField              matlab.ui.control.NumericEditField
        ReferanceEditFieldLabel         matlab.ui.control.Label
        GetValuesButton                 matlab.ui.control.Button
        DateandTimePanel                matlab.ui.container.Panel
        EditField_11                    matlab.ui.control.EditField
        TimeEditField_2                 matlab.ui.control.EditField
        TimeEditField_2Label            matlab.ui.control.Label
        SetDateButton                   matlab.ui.control.Button
        DatePicker                      matlab.ui.control.DatePicker
        DatePickerLabel                 matlab.ui.control.Label
        DateEditField                   matlab.ui.control.EditField
        DateEditFieldLabel              matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.EditField
        TimeEditFieldLabel              matlab.ui.control.Label
        UpdateDateButton                matlab.ui.control.Button
        SuppliesVoltagesPanel           matlab.ui.container.Panel
        GetVoltagesButton               matlab.ui.control.Button
        VSupplyPanel_9                  matlab.ui.container.Panel
        VoltageEditField_9              matlab.ui.control.NumericEditField
        VoltageEditField_9Label         matlab.ui.control.Label
        VSupplyPanel_8                  matlab.ui.container.Panel
        VoltageEditField_8              matlab.ui.control.NumericEditField
        VoltageEditField_8Label         matlab.ui.control.Label
        VSupplyPanel_7                  matlab.ui.container.Panel
        VoltageEditField_7              matlab.ui.control.NumericEditField
        VoltageEditField_7Label         matlab.ui.control.Label
        VSupplyPanel_6                  matlab.ui.container.Panel
        VoltageEditField_6              matlab.ui.control.NumericEditField
        VoltageEditField_6Label         matlab.ui.control.Label
        VSupplyPanel                    matlab.ui.container.Panel
        VoltageEditField                matlab.ui.control.NumericEditField
        VoltageEditFieldLabel           matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        Tab                             matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        RightPanel                      matlab.ui.container.Panel
        DisplayPanel                    matlab.ui.container.Panel
        PlotButton                      matlab.ui.control.Button
        PrintButton                     matlab.ui.control.Button
        SaveButton_2                    matlab.ui.control.Button
        ZoomoutButton                   matlab.ui.control.Button
        ZoominButton                    matlab.ui.control.Button
        YAxisButtonGroup                matlab.ui.container.ButtonGroup
        TransmissionButton              matlab.ui.control.RadioButton
        AbsorbtionButton                matlab.ui.control.RadioButton
        IntensityButton                 matlab.ui.control.RadioButton
        XAxisButtonGroup                matlab.ui.container.ButtonGroup
        WavelengthButton                matlab.ui.control.RadioButton
        ClearChartButton                matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        ReadButton                      matlab.ui.control.Button
        DarkButton                      matlab.ui.control.Button
        SampleCheckBox                  matlab.ui.control.CheckBox
        ReferanceCheckBox               matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: D2Button
        function D2ButtonPushed(app, event)
            Respond = set_lamp_motor("UV");
            if Respond == "moved"
            app.EditField_2.Value = "D2";
            elseif Respond == "failed to move"
            app.EditField_2.Value = " ";
            end
                
        end

        % Button pushed function: TungstenButton
        function TungstenButtonPushed(app, event)
            Respond = set_lamp_motor("Visible");
            if Respond == "moved"
            app.EditField_2.Value = "Tungsten";
            elseif Respond == "failed to move"
            app.EditField_2.Value = " ";
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
        value = app.Switch.Value;
        if strcmp(value,'On')
           current_status = Set_UV_Lamp("ON");
           if current_status== "on"
           app.EditField_3.Value = "ON";
           end
        elseif strcmp(value,'Off')
           current_status = Set_UV_Lamp("OFF"); 
           if current_status== "off"
           app.EditField_3.Value = "OFF";
           end   
        end


        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
        value = app.Switch_2.Value;
        if strcmp(value,'On')
           current_status = Set_Visible_Lamp("ON");
            if current_status== "on"
           app.EditField_4.Value = "ON";
           end
        elseif strcmp(value,'Off')
           current_status = Set_Visible_Lamp("OFF");
            if current_status== "off"
           app.EditField_4.Value = "OFF";
            end    
        end


        end

        % Button pushed function: D2Button_2
        function D2Button_2Pushed(app, event)
           status = get_lamp_status("UV");
         if status == "on"
             app.EditField_5.Value = "ON";
         end 
         if status == "off"
            app.EditField_5.Value = "OFF";
        end
        
        end

        % Button pushed function: TungstenButton_2
        function TungstenButton_2Pushed(app, event)
           status = get_lamp_status("Visible");
        if status == "on"
             app.EditField_5.Value = "ON";
        end 
        
        if status == "off"
            app.EditField_5.Value = "OFF";
        end
              
        end

        % Button pushed function: UpdateCurrentPositionButton_2
        function UpdateCurrentPositionButton_2Pushed(app, event)
            Current_Position = get_lamp_motor_position();
            app.CurrentPositionEditField_5.Value = Current_Position;
        end

        % Button pushed function: UpdateCurrentWavelengthButton_2
        function UpdateCurrentWavelengthButton_2Pushed(app, event)
            app.CurrentWavelengthEditField_2.Value = 100;
        end

        % Button pushed function: HomePositionButton_2
        function HomePositionButton_2Pushed(app, event)
           Current_step = go_home("lamp");
           app.HomePositionEditField_2.Value = Current_step;
            
           % app.CurrentPositionEditField_2.Value = 20;
            % app.CurrentWavelengthEditField_2.Value = 500;
        end

        % Button pushed function: UpdateCurrentPositionButton_3
        function UpdateCurrentPositionButton_3Pushed(app, event)
            app.CurrentPositionEditField_4.Value = 10;
        end

        % Button pushed function: UpdateCurrentWavelengthButton_3
        function UpdateCurrentWavelengthButton_3Pushed(app, event)
           app.CurrentWavelengthEditField_3.Value = 100;
        end

        % Button pushed function: HomePositionButton_3
        function HomePositionButton_3Pushed(app, event)
            Current_step=go_home("filter");
            app.HomePositionEditField_3.Value = Current_step;
            %app.CurrentPositionEditField_4.Value = 20;
            %app.CurrentWavelengthEditField_3.Value = 500;
        end

        % Button pushed function: ZoominButton
        function ZoominButtonPushed(app, event)
            
        end

        % Button pushed function: UpdateCurrentPositionButton
        function UpdateCurrentPositionButtonPushed(app, event)
            app.CurrentPositionEditField.Value = 10;
        end

        % Button pushed function: UpdateCurrentWavelengthButton
        function UpdateCurrentWavelengthButtonPushed(app, event)
            %app.CurrentWavelengthEditField.Value = 100;
            Current_Wavelength = get_current_wavelength();
            app.CurrentWavelengthEditField.Value = Current_Wavelength;
        end

        % Button pushed function: HomePositionButton
        function HomePositionButtonPushed(app, event)
            Current_step = go_home("grating");
            app.HomePositionEditField.Value = Current_step ;
            %app.CurrentPositionEditField.Value = 20;
            %app.CurrentWavelengthEditField.Value = 500;
        end

        % Button pushed function: GetVoltagesButton
        function GetVoltagesButtonPushed(app, event)
            [Pos_12v,Neg_12v,Supply_5v,Supply_3v,Supply_12v] = get_voltages();
            app.VoltageEditField.Value = Pos_12v;
            app.VoltageEditField_6.Value = Neg_12v;
            app.VoltageEditField_7.Value = Supply_12v;
            app.VoltageEditField_8.Value = Supply_5v;
            app.VoltageEditField_9.Value = Supply_3v;
        end

        % Callback function
        function UpdateTimeButtonPushed(app, event)
           t = datetime("now");
           y = string(t);
           z = split(y,' ',1);
            app.TimeEditField.Value = z(2);
        end

        % Button pushed function: UpdateDateButton
        function UpdateDateButtonPushed(app, event)
            [Date,Time] = get_date_time();
            app.DateEditField.Value = Date;
            app.TimeEditField.Value = Time;
            
        end

        % Button pushed function: GetValuesButton
        function GetValuesButtonPushed(app, event)
            [Reference,Reference_Amplified,Sample,Sample_Amplified,Reference_gain_value,Sample_gain_value] = get_detector_readings();
            app.ReferanceEditField.Value = Reference;
            app.SampleEditField.Value = Sample;
            app.ReferenceAmplifiedEditField.Value = Reference_Amplified;
            app.SampleAmplifiedEditField.Value = Sample_Amplified ;
            app.ReferenceGainValueEditField.Value = Reference_gain_value;
            app.SampleGainValueEditField.Value = Sample_gain_value; 
            
            
        end

        % Button pushed function: ChangeReferenceGainValuetoButton
        function ChangeReferenceGainValuetoButtonPushed(app, event)
           gain_value = app.slider.Value;
           respond = set_ref_gain(gain_value);
           if respond == "applied"
            [Reference,Reference_Amplified,Sample,Sample_Amplified,Reference_gain_value,Sample_gain_value] = get_detector_readings();
            app.ReferanceEditField.Value = Reference;
            app.SampleEditField.Value = Sample;
            app.ReferenceAmplifiedEditField.Value = Reference_Amplified;
            app.SampleAmplifiedEditField.Value = Sample_Amplified ;
            app.ReferenceGainValueEditField.Value = Reference_gain_value;
            app.SampleGainValueEditField.Value = Sample_gain_value; 
            
              
           end
           
        end

        % Button pushed function: getmotorsstepsButton
        function getmotorsstepsButtonPushed(app, event)
            [Lamp_motor_steps,Grating_motor_steps,Filter_motor_steps,Current_wavelength]= Send_Current_motors_position();
            app.Lamp_Motor_StepsEditField.Value = Lamp_motor_steps;
            app.Grating_Motor_StepsEditField.Value = Grating_motor_steps;
            app.Filter_Motor_StepsEditField.Value = Filter_motor_steps;
            app.Current_WavelengthEditField.Value = Current_wavelength;
        end

        % Callback function
        function MoveButtonPushed(app, event)
          
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed2(app, event)
           x = app.MoveMotortoFilterNoDropDown.Value;
           switch x
               case "F1"  
                         respond = set_filter_motor("F1");
               case "F2"  
                         respond = set_filter_motor("F2");
               case "F3"  
                         respond = set_filter_motor("F3");
               case "F4"  
                         respond = set_filter_motor("F4");
               case "F5"  
                         respond = set_filter_motor("F5");
               case "F6"  
                         respond = set_filter_motor("F6");
               case "F7"  
                         respond = set_filter_motor("F7");
               case "F8"  
                         respond = set_filter_motor("F8");
                                  
           end  
           app.RespondEditField.Value = respond;
        end

        % Button pushed function: MoveButton_2
        function MoveButton_2Pushed(app, event)
            No_of_steps = app.MoveMotortoLeftNoofStepsEditField.Value;
            respond = set_filtermotor_to_left(No_of_steps);
            app.RespondEditField_2.Value = respond;
        end

        % Button pushed function: MoveButton_3
        function MoveButton_3Pushed(app, event)
           No_of_steps = app.MoveMotortoRightNoofStepsEditField.Value;
           respond = set_filtermotor_to_right(No_of_steps);
           app.RespondEditField_3.Value =  respond;
        end

        % Button pushed function: SaveCalibrationButton
        function SaveCalibrationButtonPushed(app, event)
          no_of_steps = app.SaveFilterStepEditField.Value;
          respond = save_filter_step(no_of_steps);
          app.RespondEditField_4.Value = respond;
          
        end

        % Button pushed function: SaveCalibrationButton_2
        function SaveCalibrationButton_2Pushed(app, event)
           no_of_steps = app.SaveFilterWaveEditField.Value;
           respond = save_filter_wave(no_of_steps);
           app.ReapondEditField_5.Value = respond;
        end

        % Button pushed function: MoveButton_4
        function MoveButton_4Pushed(app, event)
            value_of_wavelength = app.MoveMotorToWavelengthNoEditField.Value;
           respond = set_grating_motor(value_of_wavelength);
           app.EditField_6.Value = respond;
        end

        % Button pushed function: MoveButton_5
        function MoveButton_5Pushed(app, event)
            No_Of_Steps = app.MoveMotortoLeftNoofStepsEditField_2.Value;
            respond = set_gratingrmotor_to_left(No_Of_Steps);
            app.EditField2.Value = respond ;
        end

        % Button pushed function: MoveButton_6
        function MoveButton_6Pushed(app, event)
            No_Of_Steps = app.MoveMotortoRightNoofStepsEditField_2.Value;
            respond = set_gratingmotor_to_right(No_Of_Steps);
            app.EditField3.Value = respond;
        end

        % Button pushed function: SaveCalibrationButton_3
        function SaveCalibrationButton_3Pushed(app, event)
            no_of_steps = app.SaveGratingStepEditField.Value;
            respond = save_grating_step(no_of_steps);
            app.EditField_7.Value = respond;
        end

        % Button pushed function: SaveCalibrationButton_4
        function SaveCalibrationButton_4Pushed(app, event)
            no_of_steps = app.SaveGratingWaveEditField.Value;
            respond = save_grating_wave(no_of_steps);
            app.EditField_8.Value =  respond;
        end

        % Button pushed function: SetDateButton
        function SetDateButtonPushed(app, event)
            date = app.DatePicker.Value;
            time = app.TimeEditField_2.Value;
            respond = set_date_time(date,time);
            app.EditField_11.Value = respond;
        end

        % Value changed function: DatePicker
        function DatePickerValueChanged(app, event)
            value = app.DatePicker.Value;
            
        end

        % Button pushed function: ChangeSampleGainValuetoButton
        function ChangeSampleGainValuetoButtonPushed(app, event)
            gain_value = app.slider2.Value;
            respond = set_sample_gain(gain_value);
            if respond == "applied"
            [Reference,Reference_Amplified,Sample,Sample_Amplified,Reference_gain_value,Sample_gain_value] = get_detector_readings();
            app.ReferanceEditField.Value = Reference;
            app.SampleEditField.Value = Sample;
            app.ReferenceAmplifiedEditField.Value = Reference_Amplified;
            app.SampleAmplifiedEditField.Value = Sample_Amplified ;
            app.ReferenceGainValueEditField.Value = Reference_gain_value;
            app.SampleGainValueEditField.Value = Sample_gain_value; 
            
              
           end
           
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_9.Value = changingValue;
        end

        % Value changing function: Slider2
        function Slider2ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_10.Value = changingValue;
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
              data1 = readtable("mydata.xlsx",'Sheet',1);  
              app.UITable.Data = data1;
              %app.UITable.ColumnName(1) = {'Intensity_Reference'};
              %app.UITable.ColumnName(2) = {'Intensity_Sample'};
              %app.UITable.ColumnName(3) = {'Absorption'};
              %app.UITable.ColumnName(4) = {'Transmission'};
              app.UITable.ColumnName = data1.Properties.VariableNames;


        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            if (app.IntensityButton.Value == 1)
               
                 ylabel(app.UIAxes, {'Intensity'});
            end 
            if (app.AbsorbtionButton.Value == 1)
                
                 ylabel(app.UIAxes, {'Absorbtion'});
            end 
            if (app.TransmissionButton.Value == 1)
                
                 ylabel(app.UIAxes, {'Transmission'});
            end 
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
             start = app.StartWavelengthEditField.Value;
             step = app.StepWavelengthEditField.Value;
             stop = app.StopWavelengthEditField.Value;
             values3 = Scan(start,step,stop);
             Intensity_Reference = values3(:,1);
             Intensity_Sample = values3(:,2);
             Absorption = values3(:,3);
             Transmission = values3(:,4);
             T = table(Intensity_Reference,Intensity_Sample,Absorption,Transmission);
             filename = 'C:\Users\DELL\Downloads\data_of_one_sample.xlsx';
             writetable(T,filename);
             winopen(filename);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
        Stop_WL = app.StopWavelengthEditField.Value;
        Start_WL = app.StartWavelengthEditField.Value;
        Step_WL = app.StepWavelengthEditField.Value;
        x = [Stop_WL:Step_WL:Start_WL];
        data = app.UITable.Data;
        if (app.IntensityButton.Value == 1 && app.Reference1CheckBox.Value ==1)
            y = table2array(data(:,1));
            plot(app.UIAxes,x,y);
        end
        if (app.IntensityButton.Value ==1 && app.SampleCheckBox.Value ==1)
            y = table2array(data(:,2));
            plot(app.UIAxes,x,y);
        end
        if (app.AbsorbtionButton.Value ==1)
            y = table2array(data(:,3));
            plot(app.UIAxes,x,y);
        end
         if (app.TransmissionButton.Value ==1)
            y = table2array(data(:,4));
            plot(app.UIAxes,x,y);
        end


        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
             num = xlsread('C:\Users\DELL\Downloads\data_of_one_sample.xlsx');
             sample_number = app.SampleNoEditField.Value;
             Intensity_Reference = num(:,1);
             Intensity_Sample = num(:,2);
             Absorption = num(:,3);
             Transmission = num(:,4);
             T = table(Intensity_Reference,Intensity_Sample,Absorption,Transmission);
             filename = 'C:\Users\DELL\Downloads\data_of_samples.xlsx';
             writetable(T,filename,'Sheet',sample_number);
             winopen(filename);
           
           
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {664, 664};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {447, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.AlignmentTab = uitab(app.TabGroup);
            app.AlignmentTab.Title = 'Alignment';
            app.LampChangeSetupPanel = uipanel(app.AlignmentTab);
            app.LampChangeSetupPanel.Title = '1. Lamp Change Setup';
            app.LampChangePanel = uipanel(app.LampChangeSetupPanel);
            app.LampChangePanel.Title = 'Lamp Change';
            app.D2Button = uibutton(app.LampChangePanel, 'push');
            app.D2Button.ButtonPushedFcn = createCallbackFcn(app, @D2ButtonPushed, true);
            app.D2Button.Text = 'D2';
            app.TungstenButton = uibutton(app.LampChangePanel, 'push');
            app.TungstenButton.ButtonPushedFcn = createCallbackFcn(app, @TungstenButtonPushed, true);
            app.TungstenButton.Text = 'Tungsten';
            app.EditField_2 = uieditfield(app.LampChangePanel, 'text');
            app.D2ONOFFPanel = uipanel(app.LampChangeSetupPanel);
            app.D2ONOFFPanel.Title = 'D2 ON/OFF';
            app.Switch = uiswitch(app.D2ONOFFPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.EditField_3 = uieditfield(app.D2ONOFFPanel, 'text');
            app.TungstenONOFFPanel = uipanel(app.LampChangeSetupPanel);
            app.TungstenONOFFPanel.Title = 'Tungsten ON/OFF';
            app.Switch_2 = uiswitch(app.TungstenONOFFPanel, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.EditField_4 = uieditfield(app.TungstenONOFFPanel, 'text');
            app.LampCheckONOFFPanel = uipanel(app.LampChangeSetupPanel);
            app.LampCheckONOFFPanel.Title = 'Lamp Check ON/OFF';
            app.D2Button_2 = uibutton(app.LampCheckONOFFPanel, 'push');
            app.D2Button_2.ButtonPushedFcn = createCallbackFcn(app, @D2Button_2Pushed, true);
            app.D2Button_2.Text = 'D2';
            app.TungstenButton_2 = uibutton(app.LampCheckONOFFPanel, 'push');
            app.TungstenButton_2.ButtonPushedFcn = createCallbackFcn(app, @TungstenButton_2Pushed, true);
            app.TungstenButton_2.Text = 'Tungsten';
            app.EditField_5 = uieditfield(app.LampCheckONOFFPanel, 'text');
            app.UpdateCurrentPositionButton_2 = uibutton(app.LampChangeSetupPanel, 'push');
            app.UpdateCurrentPositionButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateCurrentPositionButton_2Pushed, true);
            app.UpdateCurrentPositionButton_2.Text = 'Update Current Position';
            app.UpdateCurrentWavelengthButton_2 = uibutton(app.LampChangeSetupPanel, 'push');
            app.UpdateCurrentWavelengthButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateCurrentWavelengthButton_2Pushed, true);
            app.UpdateCurrentWavelengthButton_2.Text = 'Update Current Wavelength';
            app.CurrentWavelengthEditField_2Label = uilabel(app.LampChangeSetupPanel);
            app.CurrentWavelengthEditField_2Label.Text = 'Current Wavelength:';
            app.CurrentWavelengthEditField_2 = uieditfield(app.LampChangeSetupPanel, 'numeric');
            app.HomePositionButton_2 = uibutton(app.LampChangeSetupPanel, 'push');
            app.HomePositionButton_2.ButtonPushedFcn = createCallbackFcn(app, @HomePositionButton_2Pushed, true);
            app.HomePositionButton_2.Text = 'Home Position';
            app.HomePositionEditField_2Label = uilabel(app.LampChangeSetupPanel);
            app.HomePositionEditField_2Label.Text = 'Home Position:';
            app.HomePositionEditField_2 = uieditfield(app.LampChangeSetupPanel, 'numeric');
            app.HomePositionEditField_2.Value = 10;
            app.CurrentPositionEditField_5Label = uilabel(app.LampChangeSetupPanel);
            app.CurrentPositionEditField_5Label.Text = 'Current Position:';
            app.CurrentPositionEditField_5 = uieditfield(app.LampChangeSetupPanel, 'text');
            app.FilterSetupPanel = uipanel(app.AlignmentTab);
            app.FilterSetupPanel.Title = '2. Filter Setup';
            app.UpdateCurrentPositionButton_3 = uibutton(app.FilterSetupPanel, 'push');
            app.UpdateCurrentPositionButton_3.ButtonPushedFcn = createCallbackFcn(app, @UpdateCurrentPositionButton_3Pushed, true);
            app.UpdateCurrentPositionButton_3.Text = 'Update Current Position';
            app.UpdateCurrentWavelengthButton_3 = uibutton(app.FilterSetupPanel, 'push');
            app.UpdateCurrentWavelengthButton_3.ButtonPushedFcn = createCallbackFcn(app, @UpdateCurrentWavelengthButton_3Pushed, true);
            app.UpdateCurrentWavelengthButton_3.Text = 'Update Current Wavelength';
            app.CurrentPositionEditField_4Label = uilabel(app.FilterSetupPanel);
            app.CurrentPositionEditField_4Label.Text = 'Current Position :';
            app.CurrentPositionEditField_4 = uieditfield(app.FilterSetupPanel, 'numeric');
            app.CurrentWavelengthEditField_3Label = uilabel(app.FilterSetupPanel);
            app.CurrentWavelengthEditField_3Label.Text = 'Current Wavelength:';
            app.CurrentWavelengthEditField_3 = uieditfield(app.FilterSetupPanel, 'numeric');
            app.SaveCalibrationButton = uibutton(app.FilterSetupPanel, 'push');
            app.SaveCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCalibrationButtonPushed, true);
            app.SaveCalibrationButton.Text = 'Save Calibration';
            app.HomePositionLabel = uilabel(app.FilterSetupPanel);
            app.HomePositionLabel.Text = 'Home Position:';
            app.HomePositionEditField_3 = uieditfield(app.FilterSetupPanel, 'numeric');
            app.HomePositionEditField_3.Value = 10;
            app.MoveMotortoFilterNoDropDownLabel = uilabel(app.FilterSetupPanel);
            app.MoveMotortoFilterNoDropDownLabel.Text = 'Move Motor to Filter No:';
            app.MoveMotortoFilterNoDropDown = uidropdown(app.FilterSetupPanel);
            app.MoveMotortoFilterNoDropDown.Items = {'F1', 'F2', 'F3', 'F4', 'F5', 'F6', 'F7', 'F8'};
            app.MoveMotortoFilterNoDropDown.Value = 'F1';
            app.MoveButton = uibutton(app.FilterSetupPanel, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed2, true);
            app.MoveButton.Text = 'Move';
            app.RespondLabel = uilabel(app.FilterSetupPanel);
            app.RespondLabel.Text = 'Respond:';
            app.RespondEditField = uieditfield(app.FilterSetupPanel, 'text');
            app.MoveMotortoLeftNoofStepsEditFieldLabel = uilabel(app.FilterSetupPanel);
            app.MoveMotortoLeftNoofStepsEditFieldLabel.Text = 'Move Motor to Left No of Steps:';
            app.MoveMotortoLeftNoofStepsEditField = uieditfield(app.FilterSetupPanel, 'numeric');
            app.MoveButton_2 = uibutton(app.FilterSetupPanel, 'push');
            app.MoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_2Pushed, true);
            app.MoveButton_2.Text = 'Move';
            app.RespondLabel_2 = uilabel(app.FilterSetupPanel);
            app.RespondLabel_2.Text = 'Respond:';
            app.RespondEditField_2 = uieditfield(app.FilterSetupPanel, 'text');
            app.MoveMotortoRightNoofStepsEditFieldLabel = uilabel(app.FilterSetupPanel);
            app.MoveMotortoRightNoofStepsEditFieldLabel.Text = 'Move Motor to Right No of Steps:';
            app.MoveMotortoRightNoofStepsEditField = uieditfield(app.FilterSetupPanel, 'numeric');
            app.MoveButton_3 = uibutton(app.FilterSetupPanel, 'push');
            app.MoveButton_3.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_3Pushed, true);
            app.MoveButton_3.Text = 'Move';
            app.RespondEditField_3Label = uilabel(app.FilterSetupPanel);
            app.RespondEditField_3Label.Text = 'Respond:';
            app.RespondEditField_3 = uieditfield(app.FilterSetupPanel, 'text');
            app.HomePositionButton_3 = uibutton(app.FilterSetupPanel, 'push');
            app.HomePositionButton_3.ButtonPushedFcn = createCallbackFcn(app, @HomePositionButton_3Pushed, true);
            app.HomePositionButton_3.Text = 'Home Position';
            app.SaveFilterStepEditFieldLabel = uilabel(app.FilterSetupPanel);
            app.SaveFilterStepEditFieldLabel.Text = 'Save Filter Step:';
            app.SaveFilterStepEditField = uieditfield(app.FilterSetupPanel, 'numeric');
            app.SaveFilterWaveEditFieldLabel = uilabel(app.FilterSetupPanel);
            app.SaveFilterWaveEditFieldLabel.Text = 'Save Filter Wave:';
            app.SaveFilterWaveEditField = uieditfield(app.FilterSetupPanel, 'numeric');
            app.SaveCalibrationButton_2 = uibutton(app.FilterSetupPanel, 'push');
            app.SaveCalibrationButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveCalibrationButton_2Pushed, true);
            app.SaveCalibrationButton_2.Text = 'Save Calibration';
            app.RespondLabel_3 = uilabel(app.FilterSetupPanel);
            app.RespondLabel_3.Text = 'Respond:';
            app.RespondEditField_4 = uieditfield(app.FilterSetupPanel, 'text');
            app.RespondLabel_4 = uilabel(app.FilterSetupPanel);
            app.RespondLabel_4.Text = 'Respond:';
            app.RespondEditField_5 = uieditfield(app.FilterSetupPanel, 'text');
            app.GratingTab = uitab(app.TabGroup);
            app.GratingTab.Title = 'Grating';
            app.GratingSetupPanel = uipanel(app.GratingTab);
            app.GratingSetupPanel.Title = '1.Grating Setup';
            app.HomePositionEditFieldLabel = uilabel(app.GratingSetupPanel);
            app.HomePositionEditFieldLabel.Text = 'Home Position';
            app.HomePositionEditField = uieditfield(app.GratingSetupPanel, 'numeric');
            app.HomePositionEditField.Value = 10;
            app.SaveButton = uibutton(app.GratingSetupPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.HomePositionButton = uibutton(app.GratingSetupPanel, 'push');
            app.HomePositionButton.ButtonPushedFcn = createCallbackFcn(app, @HomePositionButtonPushed, true);
            app.HomePositionButton.Text = 'Home Position';
            app.UpdateCurrentPositionButton = uibutton(app.GratingSetupPanel, 'push');
            app.UpdateCurrentPositionButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCurrentPositionButtonPushed, true);
            app.UpdateCurrentPositionButton.Text = 'Update Current Position';
            app.CurrentPositionLabel = uilabel(app.GratingSetupPanel);
            app.CurrentPositionLabel.Text = 'Current Position :';
            app.CurrentPositionEditField = uieditfield(app.GratingSetupPanel, 'numeric');
            app.MeasureNoLabel = uilabel(app.GratingSetupPanel);
            app.MeasureNoLabel.Text = 'Measure No. :';
            app.MeasureNoEditField = uieditfield(app.GratingSetupPanel, 'numeric');
            app.StopWavelengthEditFieldLabel = uilabel(app.GratingSetupPanel);
            app.StopWavelengthEditFieldLabel.Text = 'Stop Wavelength :';
            app.StopWavelengthEditField = uieditfield(app.GratingSetupPanel, 'numeric');
            app.StartWavelengthEditFieldLabel = uilabel(app.GratingSetupPanel);
            app.StartWavelengthEditFieldLabel.Text = 'Start Wavelength:';
            app.StartWavelengthEditField = uieditfield(app.GratingSetupPanel, 'numeric');
            app.SaveButton_3 = uibutton(app.GratingSetupPanel, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Text = 'Save';
            app.UpdateCurrentWavelengthButton = uibutton(app.GratingSetupPanel, 'push');
            app.UpdateCurrentWavelengthButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCurrentWavelengthButtonPushed, true);
            app.UpdateCurrentWavelengthButton.Text = 'Update Current Wavelength';
            app.CurrentWavelengthEditFieldLabel = uilabel(app.GratingSetupPanel);
            app.CurrentWavelengthEditFieldLabel.Text = 'Current Wavelength:';
            app.CurrentWavelengthEditField = uieditfield(app.GratingSetupPanel, 'numeric');
            app.MoveMotorToWavelengthNoEditFieldLabel = uilabel(app.GratingSetupPanel);
            app.MoveMotorToWavelengthNoEditFieldLabel.Text = 'Move Motor To Wavelength No:';
            app.MoveMotorToWavelengthNoEditField = uieditfield(app.GratingSetupPanel, 'numeric');
            app.MoveButton_4 = uibutton(app.GratingSetupPanel, 'push');
            app.MoveButton_4.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_4Pushed, true);
            app.MoveButton_4.Text = 'Move';
            app.MoveMotortoLeftNoofStepsLabel = uilabel(app.GratingSetupPanel);
            app.MoveMotortoLeftNoofStepsLabel.Text = 'Move Motor to Left No of Steps:';
            app.MoveMotortoLeftNoofStepsEditField_2 = uieditfield(app.GratingSetupPanel, 'numeric');
            app.MoveButton_5 = uibutton(app.GratingSetupPanel, 'push');
            app.MoveButton_5.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_5Pushed, true);
            app.MoveButton_5.Text = 'Move';
            app.MoveMotortoRightNoofStepsLabel = uilabel(app.GratingSetupPanel);
            app.MoveMotortoRightNoofStepsLabel.Text = 'Move Motor to Right No of Steps:';
            app.MoveMotortoRightNoofStepsEditField_2 = uieditfield(app.GratingSetupPanel, 'numeric');
            app.MoveButton_6 = uibutton(app.GratingSetupPanel, 'push');
            app.MoveButton_6.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_6Pushed, true);
            app.MoveButton_6.Text = 'Move';
            app.SaveGratingStepLabel = uilabel(app.GratingSetupPanel);
            app.SaveGratingStepLabel.Text = 'Save Grating Step:';
            app.SaveGratingStepEditField = uieditfield(app.GratingSetupPanel, 'numeric');
            app.SaveCalibrationButton_3 = uibutton(app.GratingSetupPanel, 'push');
            app.SaveCalibrationButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveCalibrationButton_3Pushed, true);
            app.SaveCalibrationButton_3.Text = 'Save Calibration';
            app.SaveGratingWaveLabel = uilabel(app.GratingSetupPanel);
            app.SaveGratingWaveLabel.Text = 'Save Grating Wave:';
            app.SaveGratingWaveEditField = uieditfield(app.GratingSetupPanel, 'numeric');
            app.SaveCalibrationButton_4 = uibutton(app.GratingSetupPanel, 'push');
            app.SaveCalibrationButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveCalibrationButton_4Pushed, true);
            app.SaveCalibrationButton_4.Text = 'Save Calibration';
            app.EditField_6 = uieditfield(app.GratingSetupPanel, 'text');
            app.EditField2 = uieditfield(app.GratingSetupPanel, 'text');
            app.EditField3 = uieditfield(app.GratingSetupPanel, 'text');
            app.EditField_7 = uieditfield(app.GratingSetupPanel, 'text');
            app.EditField_8 = uieditfield(app.GratingSetupPanel, 'text');
            app.StepWavelengthEditFieldLabel = uilabel(app.GratingSetupPanel);
            app.StepWavelengthEditFieldLabel.Text = 'Step Wavelength:';
            app.StepWavelengthEditField = uieditfield(app.GratingSetupPanel, 'numeric');
            app.ScanButton = uibutton(app.GratingSetupPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.SampleNoEditFieldLabel = uilabel(app.GratingSetupPanel);
            app.SampleNoEditFieldLabel.Text = 'Sample No:';
            app.SampleNoEditField = uieditfield(app.GratingSetupPanel, 'numeric');
            app.Get_Motors_StepsPanel = uipanel(app.GratingTab);
            app.Get_Motors_StepsPanel.Title = 'Get_Motors_Steps';
            app.getmotorsstepsButton = uibutton(app.Get_Motors_StepsPanel, 'push');
            app.getmotorsstepsButton.ButtonPushedFcn = createCallbackFcn(app, @getmotorsstepsButtonPushed, true);
            app.getmotorsstepsButton.Text = 'get-motors-steps';
            app.Lamp_Motor_StepsEditFieldLabel = uilabel(app.Get_Motors_StepsPanel);
            app.Lamp_Motor_StepsEditFieldLabel.Text = 'Lamp_Motor_Steps:';
            app.Lamp_Motor_StepsEditField = uieditfield(app.Get_Motors_StepsPanel, 'numeric');
            app.Filter_Motor_StepsEditFieldLabel = uilabel(app.Get_Motors_StepsPanel);
            app.Filter_Motor_StepsEditFieldLabel.Text = 'Filter_Motor_Steps:';
            app.Filter_Motor_StepsEditField = uieditfield(app.Get_Motors_StepsPanel, 'numeric');
            app.Grating_Motor_StepsEditFieldLabel = uilabel(app.Get_Motors_StepsPanel);
            app.Grating_Motor_StepsEditFieldLabel.Text = 'Grating_Motor_Steps:';
            app.Grating_Motor_StepsEditField = uieditfield(app.Get_Motors_StepsPanel, 'numeric');
            app.Current_WavelengthLabel = uilabel(app.Get_Motors_StepsPanel);
            app.Current_WavelengthLabel.Text = 'Current_Wavelength:';
            app.Current_WavelengthEditField = uieditfield(app.Get_Motors_StepsPanel, 'numeric');
            app.ReadingsTab = uitab(app.TabGroup);
            app.ReadingsTab.Title = 'Readings';
            app.Panel = uipanel(app.ReadingsTab);
            app.Panel.Title = 'Panel';
            app.SuppliesVoltagesPanel = uipanel(app.ReadingsTab);
            app.SuppliesVoltagesPanel.Title = '2. Supplies Voltages';
            app.VSupplyPanel = uipanel(app.SuppliesVoltagesPanel);
            app.VSupplyPanel.Title = '+12V Supply';
            app.VoltageEditFieldLabel = uilabel(app.VSupplyPanel);
            app.VoltageEditFieldLabel.Text = 'Voltage';
            app.VoltageEditField = uieditfield(app.VSupplyPanel, 'numeric');
            app.VSupplyPanel_6 = uipanel(app.SuppliesVoltagesPanel);
            app.VSupplyPanel_6.Title = '-12V Supply';
            app.VoltageEditField_6Label = uilabel(app.VSupplyPanel_6);
            app.VoltageEditField_6Label.Text = 'Voltage';
            app.VoltageEditField_6 = uieditfield(app.VSupplyPanel_6, 'numeric');
            app.VSupplyPanel_7 = uipanel(app.SuppliesVoltagesPanel);
            app.VSupplyPanel_7.Title = '12V Supply';
            app.VoltageEditField_7Label = uilabel(app.VSupplyPanel_7);
            app.VoltageEditField_7Label.Text = 'Voltage';
            app.VoltageEditField_7 = uieditfield(app.VSupplyPanel_7, 'numeric');
            app.VSupplyPanel_8 = uipanel(app.SuppliesVoltagesPanel);
            app.VSupplyPanel_8.Title = '5V Supply';
            app.VoltageEditField_8Label = uilabel(app.VSupplyPanel_8);
            app.VoltageEditField_8Label.Text = 'Voltage';
            app.VoltageEditField_8 = uieditfield(app.VSupplyPanel_8, 'numeric');
            app.VSupplyPanel_9 = uipanel(app.SuppliesVoltagesPanel);
            app.VSupplyPanel_9.Title = '3.3V Supply';
            app.VoltageEditField_9Label = uilabel(app.VSupplyPanel_9);
            app.VoltageEditField_9Label.Text = 'Voltage';
            app.VoltageEditField_9 = uieditfield(app.VSupplyPanel_9, 'numeric');
            app.GetVoltagesButton = uibutton(app.SuppliesVoltagesPanel, 'push');
            app.GetVoltagesButton.ButtonPushedFcn = createCallbackFcn(app, @GetVoltagesButtonPushed, true);
            app.GetVoltagesButton.Text = 'Get Voltages';
            app.DateandTimePanel = uipanel(app.ReadingsTab);
            app.DateandTimePanel.Title = '1. Date and Time';
            app.UpdateDateButton = uibutton(app.DateandTimePanel, 'push');
            app.UpdateDateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDateButtonPushed, true);
            app.UpdateDateButton.Text = 'Update Date';
            app.TimeEditFieldLabel = uilabel(app.DateandTimePanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.DateandTimePanel, 'text');
            app.DateEditFieldLabel = uilabel(app.DateandTimePanel);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.DateandTimePanel, 'text');
            app.DatePickerLabel = uilabel(app.DateandTimePanel);
            app.DatePickerLabel.Text = 'Date Picker';
            app.DatePicker = uidatepicker(app.DateandTimePanel);
            app.DatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.SetDateButton = uibutton(app.DateandTimePanel, 'push');
            app.SetDateButton.ButtonPushedFcn = createCallbackFcn(app, @SetDateButtonPushed, true);
            app.SetDateButton.Text = 'Set Date';
            app.TimeEditField_2Label = uilabel(app.DateandTimePanel);
            app.TimeEditField_2Label.Text = 'Time';
            app.TimeEditField_2 = uieditfield(app.DateandTimePanel, 'text');
            app.EditField_11 = uieditfield(app.DateandTimePanel, 'text');
            app.PhotodetectorPanel = uipanel(app.ReadingsTab);
            app.PhotodetectorPanel.Title = '3. Photodetector';
            app.GetValuesButton = uibutton(app.PhotodetectorPanel, 'push');
            app.GetValuesButton.ButtonPushedFcn = createCallbackFcn(app, @GetValuesButtonPushed, true);
            app.GetValuesButton.Text = 'Get Values';
            app.ReferanceEditFieldLabel = uilabel(app.PhotodetectorPanel);
            app.ReferanceEditFieldLabel.Text = 'Referance ';
            app.ReferanceEditField = uieditfield(app.PhotodetectorPanel, 'numeric');
            app.SampleEditFieldLabel = uilabel(app.PhotodetectorPanel);
            app.SampleEditFieldLabel.Text = 'Sample';
            app.SampleEditField = uieditfield(app.PhotodetectorPanel, 'numeric');
            app.ReferenceAmplifiedEditFieldLabel = uilabel(app.PhotodetectorPanel);
            app.ReferenceAmplifiedEditFieldLabel.Text = 'Reference Amplified';
            app.ReferenceAmplifiedEditField = uieditfield(app.PhotodetectorPanel, 'numeric');
            app.SampleAmplifiedEditFieldLabel = uilabel(app.PhotodetectorPanel);
            app.SampleAmplifiedEditFieldLabel.Text = 'Sample Amplified';
            app.SampleAmplifiedEditField = uieditfield(app.PhotodetectorPanel, 'numeric');
            app.ReferenceGainValueEditFieldLabel = uilabel(app.PhotodetectorPanel);
            app.ReferenceGainValueEditFieldLabel.Text = 'Reference Gain Value';
            app.ReferenceGainValueEditField = uieditfield(app.PhotodetectorPanel, 'numeric');
            app.Button2 = uibutton(app.PhotodetectorPanel, 'push');
            app.Button2.Text = 'Button2';
            app.ChangeReferenceGainValuetoButton = uibutton(app.PhotodetectorPanel, 'push');
            app.ChangeReferenceGainValuetoButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeReferenceGainValuetoButtonPushed, true);
            app.ChangeReferenceGainValuetoButton.Text = 'Change Reference Gain Value to:';
            app.ChangeSampleGainValuetoButton = uibutton(app.PhotodetectorPanel, 'push');
            app.ChangeSampleGainValuetoButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeSampleGainValuetoButtonPushed, true);
            app.ChangeSampleGainValuetoButton.Text = 'Change Sample Gain Value to:';
            app.SampleGainValueEditFieldLabel = uilabel(app.PhotodetectorPanel);
            app.SampleGainValueEditFieldLabel.Text = 'Sample Gain Value';
            app.SampleGainValueEditField = uieditfield(app.PhotodetectorPanel, 'numeric');
            app.EditField_9 = uieditfield(app.PhotodetectorPanel, 'text');
            app.EditField_10 = uieditfield(app.PhotodetectorPanel, 'text');
            app.Slider2 = uislider(app.PhotodetectorPanel);
            app.Slider2.MajorTicks = [0 20 40 60 80 100];
            app.Slider2.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @Slider2ValueChanging, true);
            app.Slider2.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.Slider = uislider(app.PhotodetectorPanel);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.DisplayPanel = uipanel(app.RightPanel);
            app.DisplayPanel.Title = 'Display';
            app.UIAxes = uiaxes(app.DisplayPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, {'Wavelength'; ''; ''})
            ylabel(app.UIAxes, {'Intensity'; ''})
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ReferanceCheckBox = uicheckbox(app.DisplayPanel);
            app.ReferanceCheckBox.Text = 'Referance ';
            app.SampleCheckBox = uicheckbox(app.DisplayPanel);
            app.SampleCheckBox.Text = 'Sample ';
            app.DarkButton = uibutton(app.DisplayPanel, 'push');
            app.DarkButton.Text = 'Dark';
            app.ReadButton = uibutton(app.DisplayPanel, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.Button = uibutton(app.DisplayPanel, 'push');
            app.StopButton = uibutton(app.DisplayPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.ClearChartButton = uibutton(app.DisplayPanel, 'push');
            app.ClearChartButton.Text = 'Clear Chart';
            app.XAxisButtonGroup = uibuttongroup(app.DisplayPanel);
            app.XAxisButtonGroup.Title = 'X Axis';
            app.WavelengthButton = uiradiobutton(app.XAxisButtonGroup);
            app.WavelengthButton.Text = 'Wavelength';
            app.WavelengthButton.Value = true;
            app.YAxisButtonGroup = uibuttongroup(app.DisplayPanel);
            app.YAxisButtonGroup.Title = 'Y Axis';
            app.IntensityButton = uiradiobutton(app.YAxisButtonGroup);
            app.IntensityButton.Text = 'Intensity';
            app.IntensityButton.Value = true;
            app.AbsorbtionButton = uiradiobutton(app.YAxisButtonGroup);
            app.AbsorbtionButton.Text = 'Absorbtion';
            app.TransmissionButton = uiradiobutton(app.YAxisButtonGroup);
            app.TransmissionButton.Text = 'Transmission';
            app.ZoominButton = uibutton(app.DisplayPanel, 'push');
            app.ZoominButton.ButtonPushedFcn = createCallbackFcn(app, @ZoominButtonPushed, true);
            app.ZoominButton.Text = 'Zoom in';
            app.ZoomoutButton = uibutton(app.DisplayPanel, 'push');
            app.ZoomoutButton.Text = 'Zoom out';
            app.SaveButton_2 = uibutton(app.DisplayPanel, 'push');
            app.SaveButton_2.Text = 'Save';
            app.PrintButton = uibutton(app.DisplayPanel, 'push');
            app.PrintButton.Text = 'Print';
            app.PlotButton = uibutton(app.DisplayPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end